classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel_2                  matlab.ui.container.Panel
        ChooseButton             matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
        OpenButton               matlab.ui.control.Button
        ListBox                  matlab.ui.control.ListBox
        Panel_5                  matlab.ui.container.Panel
        RecentimagesLabel        matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        PathEditFieldLabel       matlab.ui.control.Label
        PathEditField            matlab.ui.control.EditField
        DeleteButton             matlab.ui.control.Button
        Panel_6                  matlab.ui.container.Panel
        WelcomeLabel             matlab.ui.control.Label
        ChooseandopenimageLabel  matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ListBox.Items = paths('g');
            screen = get(groot, 'ScreenSize');
            app.UIFigure.Position([1 2]) = [(screen(3) - app.UIFigure.Position(3)) / 2,(screen(4) - app.UIFigure.Position(4)) / 2];
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            closereq();
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global path;
            path = app.ListBox.Value;
            close(app.UIFigure);
            App3;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            if isempty(app.ListBox.Value)
                app.Panel_6.Visible = 'on';
                app.OpenButton.Visible = 'off';
            else
                app.PathEditField.Value = app.ListBox.Value;
                try 
                    [img, map] = imread(app.ListBox.Value);
                    app.OpenButton.Visible = 'on';
                catch
                    [img, map] = imread('Image\NotFound.png');
                    app.OpenButton.Visible = 'off';
                end
                imshow(img, map,'Parent',app.UIAxes);
                app.Panel_6.Visible = 'off';
            end        
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            path = imgetfile();
            waitfor(path);
            delete(f);
            if ~isempty(path)
                if ~ismember(path, app.ListBox.Items)
                    app.ListBox.Items{end + 1} = path;
                    paths('a', {path});
                end
                app.ListBox.Value = path;
                app.ListBoxValueChanged(event);
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if strcmp(questdlg('Are you sure delete this image?'), 'Yes')
                idx = find(strcmp(app.ListBox.Items, app.ListBox.Value), 1);
                app.ListBox.Items(idx) = [];
                app.ListBoxValueChanged(event);
                paths('w',app.ListBox.Items);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel_2 = uipanel(app.UIFigure);
            app.ChooseButton = uibutton(app.Panel_2, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.CloseButton = uibutton(app.Panel_2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.Panel_2, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Visible = 'off';
            app.OpenButton.Text = 'Open';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.Panel_5 = uipanel(app.UIFigure);
            app.RecentimagesLabel = uilabel(app.Panel_5);
            app.RecentimagesLabel.Text = 'Recent images';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.Editable = 'off';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.Panel_6 = uipanel(app.UIFigure);
            app.WelcomeLabel = uilabel(app.Panel_6);
            app.WelcomeLabel.Text = 'Welcome';
            app.ChooseandopenimageLabel = uilabel(app.Panel_6);
            app.ChooseandopenimageLabel.Text = 'Choose and open image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end