classdef App3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        sidebar             matlab.ui.container.Panel
        OptionPanel         matlab.ui.container.Panel
        OptionsLabel        matlab.ui.control.Label
        HistoryPanel        matlab.ui.container.Panel
        HistoryLabel        matlab.ui.control.Label
        OpionsWindow        matlab.ui.container.Panel
        DropDown            matlab.ui.control.DropDown
        ApplyButton         matlab.ui.control.Button
        IgnoreButton        matlab.ui.control.StateButton
        HistoryWindow       matlab.ui.container.Panel
        RedoButton          matlab.ui.control.Button
        HistoryList         matlab.ui.control.ListBox
        UndoButton          matlab.ui.control.Button
        PathPanel           matlab.ui.container.Panel
        PathEditFieldLabel  matlab.ui.control.Label
        PathEditField       matlab.ui.control.EditField
        ControlPanel        matlab.ui.container.Panel
        CloseButton         matlab.ui.control.Button
        SaveAsButton        matlab.ui.control.Button
        BackButton          matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        OptionSubPanel      matlab.ui.container.Panel
        TitleLabel          matlab.ui.control.Label
        OptionsList         matlab.ui.control.ListBox
    end


    properties (Access = private)
        img
        history
        action
    end

    methods (Access = private)
    
        function add(app, image)
            app.img = image;
            size = numel(app.HistoryList.Items);
            value = app.HistoryList.Value;
            if value ~= size
                app.history(value + 1:end) = [];
                app.HistoryList.Items(value + 1:end) = [];
                app.HistoryList.ItemsData(value + 1:end) = [];
            end
            app.history{end + 1} = app.img;
            imshow(app.img,'Parent',app.UIAxes);
            app.HistoryList.Items{end + 1} = strcat(app.action{1}, '>', app.action{2});
            app.HistoryList.ItemsData{end + 1} = value + 1;
            app.HistoryList.Value = value + 1;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screen = get(groot, 'ScreenSize');
            app.UIFigure.Position([1 2]) = [(screen(3) - app.UIFigure.Position(3)) / 2,(screen(4) - app.UIFigure.Position(4)) / 2];
            global path;
            app.PathEditField.Value = path;
            [data, map] = imread(path);
            switch typeI(data, map) 
                case 'Indexed'
                    image = ind2rgb(data, map);
                case 'Unknown'
                    % TODO : rise error
                otherwise
                    image = data;
            end
            
            app.img = image;
            app.history = {app.img};
            imshow(app.img,'Parent',app.UIAxes);
            app.HistoryList.Items = {'(Default)'};
            app.HistoryList.ItemsData = {1};
            app.HistoryList.Value = 1;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;        
            imshow(app.history{app.HistoryList.Value},'Parent',app.UIAxes);
            switch value
                case 'Default'
                    app.OptionsList.Visible = 'off';
                    app.IgnoreButton.Visible = 'off';
                    app.ApplyButton.Visible = 'off';
                    app.IgnoreButton.Visible = 'off';
                    app.OptionSubPanel.Visible = 'off';
                otherwise 
                    app.OptionSubPanel.Visible = 'on';
                    app.OptionsList.Visible = 'on';
                    app.TitleLabel.Text = value;
                    switch value
                        case 'Noise'
                            app.OptionsList.Items = {'Default' 'Salt & Pepper' 'Poisson' 'Speckle' 'Gaussian'};
                        case 'Filters'
                            app.OptionsList.Items = {'Default' 'Average' 'Median' 'Disk' 'Gaussian' 'Motion' 'Laplacian' 'Log' 'Prewitt' 'Sobel'};
                        case 'transformations'
                            app.OptionsList.Items = {'Default' 'Negative' 'Power Law' 'Brightness' 'Thresholding' 'log' 'Histogram Stretching' 'Histogram Equalization'};
                        case 'Edge detection'
                            app.OptionsList.Items = {'Default' 'Roberts' 'Sobel' 'Prewitt' 'Canny' 'Approxcanny' 'Log' 'Zerocross'};
                    end  
                    app.OptionsList.Value = 'Default';
                    app.ApplyButton.Visible = 'off';
                    app.IgnoreButton.Visible = 'off';
            end
        end

        % Value changed function: OptionsList
        function OptionsListValueChanged(app, event)
            option = app.DropDown.Value;
            value = app.OptionsList.Value;
            app.action = {option value};
            image = app.history{app.HistoryList.Value};
            switch value 
                case 'Default'
                    app.ApplyButton.Visible = 'off';
                    app.IgnoreButton.Visible = 'off';
                    imshow(app.history{app.HistoryList.Value},'Parent',app.UIAxes);
                otherwise
                    app.ApplyButton.Visible = 'on';
                    app.IgnoreButton.Value = 0;
                    app.IgnoreButtonValueChanged(event);
                    app.IgnoreButton.Visible = 'on';
                    app.img = main_function(option, image, value);
                    imshow(app.img,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            closereq();
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            closereq();
            App;
        end

        % Value changed function: HistoryList
        function HistoryListValueChanged(app, event)
            value = app.HistoryList.Value;
            switch app.OptionsList.Visible 
                case 'on'
                    app.OptionsList.Value = 'Default';
                    app.OptionsListValueChanged(event);
            end
            imshow(app.history{value},'Parent',app.UIAxes);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.add(app.img);
            app.OptionsList.Value = 'Default';
            app.OptionsListValueChanged(event);
        end

        % Value changed function: IgnoreButton
        function IgnoreButtonValueChanged(app, event)
            if app.IgnoreButton.Value
                app.IgnoreButton.Text = 'Active';
                imshow(app.history{app.HistoryList.Value},'Parent',app.UIAxes);
            else
                app.IgnoreButton.Text = 'Ignore';
                imshow(app.img,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if app.HistoryList.Value == 1 
                return
            end
            app.HistoryList.Value = app.HistoryList.Value - 1;
            app.HistoryListValueChanged(event);
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            if app.HistoryList.Value == numel(app.HistoryList.Items)
                return
            end
            app.HistoryList.Value = app.HistoryList.Value + 1;
            app.HistoryListValueChanged(event);
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
             f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            extensionString = {'*.png';'*.jpg';'*.tif';'*.*'};
            [filename,pathname] = uiputfile(extensionString,'Save table data');
            delete(f);
            if ~isequal(filename,0) && ~isequal(pathname,0)
                imwrite(app.history{end}, fullfile(pathname, filename));
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, false)
            app.sidebar = uipanel(app.UIFigure);
            app.OptionPanel = uipanel(app.sidebar);
            app.OptionsLabel = uilabel(app.OptionPanel);
            app.OptionsLabel.Text = 'Options';
            app.HistoryPanel = uipanel(app.sidebar);
            app.HistoryLabel = uilabel(app.HistoryPanel);
            app.HistoryLabel.Text = 'History';
            app.OpionsWindow = uipanel(app.sidebar);
            app.DropDown = uidropdown(app.OpionsWindow);
            app.DropDown.Items = {'Default', 'Noise', 'Filters', 'transformations', 'Edge detection'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Default';
            app.ApplyButton = uibutton(app.OpionsWindow, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Visible = 'off';
            app.ApplyButton.Text = 'Apply';
            app.IgnoreButton = uibutton(app.OpionsWindow, 'state');
            app.IgnoreButton.ValueChangedFcn = createCallbackFcn(app, @IgnoreButtonValueChanged, true);
            app.IgnoreButton.Visible = 'off';
            app.IgnoreButton.Text = 'Ignore';
            app.HistoryWindow = uipanel(app.sidebar);
            app.RedoButton = uibutton(app.HistoryWindow, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = 'Redo';
            app.HistoryList = uilistbox(app.HistoryWindow);
            app.HistoryList.Items = {};
            app.HistoryList.ValueChangedFcn = createCallbackFcn(app, @HistoryListValueChanged, true);
            app.HistoryList.Value = {};
            app.UndoButton = uibutton(app.HistoryWindow, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.PathPanel = uipanel(app.UIFigure);
            app.PathEditFieldLabel = uilabel(app.PathPanel);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.PathPanel, 'text');
            app.PathEditField.Editable = 'off';
            app.ControlPanel = uipanel(app.UIFigure);
            app.CloseButton = uibutton(app.ControlPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SaveAsButton = uibutton(app.ControlPanel, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As';
            app.BackButton = uibutton(app.ControlPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YAxisLocation = 'right';
            app.UIAxes.YTickLabel = {};
            app.OptionSubPanel = uipanel(app.UIFigure);
            app.OptionSubPanel.Visible = 'off';
            app.TitleLabel = uilabel(app.OptionSubPanel);
            app.TitleLabel.Text = 'title';
            app.OptionsList = uilistbox(app.UIFigure);
            app.OptionsList.Items = {};
            app.OptionsList.ValueChangedFcn = createCallbackFcn(app, @OptionsListValueChanged, true);
            app.OptionsList.Visible = 'off';
            app.OptionsList.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = App3()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end